classdef my_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        InitParamsTab              matlab.ui.container.Tab
        BmEditField                matlab.ui.control.NumericEditField
        BmEditFieldLabel           matlab.ui.control.Label
        PEditField                 matlab.ui.control.NumericEditField
        PEditFieldLabel            matlab.ui.control.Label
        JEditField                 matlab.ui.control.NumericEditField
        JEditFieldLabel            matlab.ui.control.Label
        LlrEditField               matlab.ui.control.NumericEditField
        LlrEditFieldLabel          matlab.ui.control.Label
        SetParametersButton        matlab.ui.control.Button
        LmEditField                matlab.ui.control.NumericEditField
        LmEditFieldLabel           matlab.ui.control.Label
        LlsEditField               matlab.ui.control.NumericEditField
        LlsEditFieldLabel          matlab.ui.control.Label
        RrEditField                matlab.ui.control.NumericEditField
        RrEditFieldLabel           matlab.ui.control.Label
        RsEditField                matlab.ui.control.NumericEditField
        RsEditFieldLabel           matlab.ui.control.Label
        GraphTab                   matlab.ui.container.Tab
        SimStopTimeEditField       matlab.ui.control.NumericEditField
        SimStopTimeEditFieldLabel  matlab.ui.control.Label
        AmplitudeEditField         matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel    matlab.ui.control.Label
        FrequencyEditField         matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel    matlab.ui.control.Label
        AmplitudeSlider            matlab.ui.control.Slider
        AmplitudeSliderLabel       matlab.ui.control.Label
        FrequencySlider            matlab.ui.control.Slider
        FrequencySliderLabel       matlab.ui.control.Label
        SimulateButton             matlab.ui.control.Button
        CheckBox_17                matlab.ui.control.CheckBox
        CheckBox_16                matlab.ui.control.CheckBox
        CheckBox_15                matlab.ui.control.CheckBox
        CheckBox_14                matlab.ui.control.CheckBox
        CheckBox_13                matlab.ui.control.CheckBox
        CheckBox_12                matlab.ui.control.CheckBox
        CheckBox_11                matlab.ui.control.CheckBox
        CheckBox_10                matlab.ui.control.CheckBox
        CheckBox_9                 matlab.ui.control.CheckBox
        CheckBox_8                 matlab.ui.control.CheckBox
        CheckBox_7                 matlab.ui.control.CheckBox
        CheckBox_6                 matlab.ui.control.CheckBox
        CheckBox_5                 matlab.ui.control.CheckBox
        CheckBox_4                 matlab.ui.control.CheckBox
        CheckBox                   matlab.ui.control.CheckBox
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        WrAxes                     matlab.ui.control.UIAxes
        VirtualRealityTab          matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            % get params
            Rs = app.RsEditField.Value;
            R1r = app.RrEditField.Value;
            Lls = app.LlrEditField.Value;
            Lm = app.FrequencyEditField.Value;
            L1lr = app.LlrEditField.Value;
            J = app.JEditField.Value;
            P = app.PEditField.Value;
            Bm = app.BmEditField.Value;

            inits=[Rs,R1r,Lls,Lm,L1lr,J,P,Bm];
            % assign to workspace
            assignin('base', 'inits', inits);
            

            t = simout.tout;
            wr = simout.yout(:, 1);

            plot(app.WrAxes,t,wr);
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            app.FrequencySlider.Value=value;
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            changingValue = event.Value;
            app.FrequencyEditField.Value=changingValue;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            Freq=app.FrequencyEditField.Value;
            Vampl=app.AmplitudeEditField.Value;
            assignin('base',"Freq",Freq);
            assignin('base',"Vampl",Vampl);  
           
            
            mdl = "my_sim";

            load_system(mdl);

            tstop = app.SimStopTimeEditField.Value;

            set_param(mdl, "StopTime", num2str(tstop));

            simout = sim(mdl);

            assignin('base', 'simout', simout);

        end

        % Value changing function: AmplitudeSlider
        function AmplitudeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.AmplitudeEditField.Value=changingValue;
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            value = app.AmplitudeEditField.Value;
            app.AmplitudeSlider.Value=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitParamsTab = uitab(app.TabGroup);
            app.InitParamsTab.Title = 'Init Params';
            app.RsEditFieldLabel = uilabel(app.InitParamsTab);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.InitParamsTab, 'numeric');
            app.RrEditFieldLabel = uilabel(app.InitParamsTab);
            app.RrEditFieldLabel.Text = 'R''r';
            app.RrEditField = uieditfield(app.InitParamsTab, 'numeric');
            app.LlsEditFieldLabel = uilabel(app.InitParamsTab);
            app.LlsEditFieldLabel.Text = 'Lls';
            app.LlsEditField = uieditfield(app.InitParamsTab, 'numeric');
            app.LmEditFieldLabel = uilabel(app.InitParamsTab);
            app.LmEditFieldLabel.Text = 'Lm';
            app.LmEditField = uieditfield(app.InitParamsTab, 'numeric');
            app.SetParametersButton = uibutton(app.InitParamsTab, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.LlrEditFieldLabel = uilabel(app.InitParamsTab);
            app.LlrEditFieldLabel.Text = 'L''lr';
            app.LlrEditField = uieditfield(app.InitParamsTab, 'numeric');
            app.JEditFieldLabel = uilabel(app.InitParamsTab);
            app.JEditFieldLabel.Text = 'J';
            app.JEditField = uieditfield(app.InitParamsTab, 'numeric');
            app.PEditFieldLabel = uilabel(app.InitParamsTab);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.InitParamsTab, 'numeric');
            app.BmEditFieldLabel = uilabel(app.InitParamsTab);
            app.BmEditFieldLabel.Text = 'Bm';
            app.BmEditField = uieditfield(app.InitParamsTab, 'numeric');
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.WrAxes = uiaxes(app.GraphTab);
            title(app.WrAxes, 'Title')
            xlabel(app.WrAxes, 'X')
            ylabel(app.WrAxes, 'Y')
            zlabel(app.WrAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GraphTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.GraphTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.CheckBox = uicheckbox(app.GraphTab);
            app.CheckBox_4 = uicheckbox(app.GraphTab);
            app.CheckBox_5 = uicheckbox(app.GraphTab);
            app.CheckBox_6 = uicheckbox(app.GraphTab);
            app.CheckBox_7 = uicheckbox(app.GraphTab);
            app.CheckBox_8 = uicheckbox(app.GraphTab);
            app.CheckBox_9 = uicheckbox(app.GraphTab);
            app.CheckBox_10 = uicheckbox(app.GraphTab);
            app.CheckBox_11 = uicheckbox(app.GraphTab);
            app.CheckBox_12 = uicheckbox(app.GraphTab);
            app.CheckBox_13 = uicheckbox(app.GraphTab);
            app.CheckBox_14 = uicheckbox(app.GraphTab);
            app.CheckBox_15 = uicheckbox(app.GraphTab);
            app.CheckBox_16 = uicheckbox(app.GraphTab);
            app.CheckBox_17 = uicheckbox(app.GraphTab);
            app.SimulateButton = uibutton(app.GraphTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.FrequencySliderLabel = uilabel(app.GraphTab);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.GraphTab);
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.FrequencySlider.Value = 50;
            app.AmplitudeSliderLabel = uilabel(app.GraphTab);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.GraphTab);
            app.AmplitudeSlider.Limits = [0 500];
            app.AmplitudeSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSliderValueChanging, true);
            app.AmplitudeSlider.Value = 325;
            app.FrequencyEditFieldLabel = uilabel(app.GraphTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.GraphTab, 'numeric');
            app.FrequencyEditField.Limits = [0 100];
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 50;
            app.AmplitudeEditFieldLabel = uilabel(app.GraphTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.GraphTab, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.Value = 325;
            app.SimStopTimeEditFieldLabel = uilabel(app.GraphTab);
            app.SimStopTimeEditFieldLabel.Text = 'Sim Stop Time';
            app.SimStopTimeEditField = uieditfield(app.GraphTab, 'numeric');
            app.SimStopTimeEditField.Value = 2;
            app.VirtualRealityTab = uitab(app.TabGroup);
            app.VirtualRealityTab.Title = 'Virtual Reality';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end